classdef AiCam < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AiCamUIFigure           matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        DevicesMenu             matlab.ui.container.Menu
        OptionsMenu             matlab.ui.container.Menu
        CaptureMenu             matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        PreviewTab              matlab.ui.container.Tab
        CameraListListBoxLabel  matlab.ui.control.Label
        CameraListListBox       matlab.ui.control.ListBox
        ResolutionsButtonGroup  matlab.ui.container.ButtonGroup
        PreviewButton           matlab.ui.control.Button
        CheckBox_slash_LT_RD    matlab.ui.control.CheckBox
        CaptureRecordTab        matlab.ui.container.Tab
        SavePathEditFieldLabel  matlab.ui.control.Label
        SavePathEditField       matlab.ui.control.EditField
        SelectPathButton        matlab.ui.control.Button
        CapturePanel            matlab.ui.container.Panel
        CaptureFramesLabel      matlab.ui.control.Label
        CaptureFramesEditField  matlab.ui.control.NumericEditField
        CaptureButton           matlab.ui.control.Button
        RecordPanel             matlab.ui.container.Panel
        ButtonStartRecord       matlab.ui.control.Button
        ButtonStopRecord        matlab.ui.control.Button
        TotalLengthEditField    matlab.ui.control.EditField
        RecordStateLamp         matlab.ui.control.Lamp
        UIAxes                  matlab.ui.control.UIAxes
        ImageProcessTab         matlab.ui.container.Tab
    end

    
    properties (Access = public)
        glCamObj; % Camrea device obj
        glCamName % Camrea name
        glCamIndex % Camera index in camera list
        glSavePath % save path
        glResolution % resolution for preview
        glCaptureFrames % frames will be capture
        glSaveFormat % save capture image formating
        glResAvalible % current camera avalibale resolutions
        glRsListTotal % total number of previous camera
    end
    
    methods (Access = public)
        
        
        function update_preview_display(obj,event,hImage)
            % This callback function updates the displayed frame and the histogram.
            % Display the current image frame.
            set(hImage, 'CData', event.Data);
            disp('AAAAAAAAAAAAAAAAAAAaaaa')
            % Select the second subplot on the figure for the histogram.
            subplot(1,2,2);
            
            % Plot the histogram. Choose 128 bins for faster update of the display.
            imhist(event.Data, 128);
            
            % Refresh the display.
            drawnow
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.glCaptureFrames = 1;
            app.glRsListTotal = 0;
            app.glSavePath = '';
            app.glSaveFormat = '';
            
            % List the camera device
            cl = webcamlist;
            n = size(cl);
            items = [];
            ItemsData = [];
            for i=1:n
                items = [items cl(i)];
                ItemsData = [ItemsData i];
            end
            if isempty(items)
                %警告对话框
                warndlg('没有可用摄像头！','警告','modal');
            else
                app.CameraListListBox.Items = items;
                app.CameraListListBox.ItemsData = ItemsData;% listbox.value
            end
            
        end

        % Value changed function: CameraListListBox
        function CameraListListBoxValueChanged(app, event)
            app.glCamIndex = app.CameraListListBox.Value;
            %             disp(app.glCamIndex)
            app.glCamName = app.CameraListListBox.Items(app.CameraListListBox.Value);
            %             disp(app.glCamName)
            app.glCamObj = webcam(app.glCamIndex);
            rs = app.glCamObj.AvailableResolutions;
            [~, app.glResAvalible] = size(rs);
            
            left = 11;
            bottom = app.ResolutionsButtonGroup.InnerPosition(4);
            width = app.ResolutionsButtonGroup.InnerPosition(3);%ButtonGroup width
            height = 20;
            
            % hide the old list
            if (app.glRsListTotal ~= 0)
                for i=1:app.glRsListTotal
                    app.ResolutionsButtonGroup.Children(i).Visible =  'off';
                end
            end
            
            % cover the old list by new resolutions list
            for i=1:app.glResAvalible
                bt = uiradiobutton(app.ResolutionsButtonGroup);
                bt.Text = rs(i);
                bt.Position = [left bottom-25*i width height];
                bt.Value = 1;
            end
            app.glRsListTotal = app.glResAvalible;
            str = sprintf('Resolutions(%d)', app.glResAvalible);
            app.ResolutionsButtonGroup.Title = str;
        end

        % Selection changed function: ResolutionsButtonGroup
        function ResolutionsButtonGroupSelectionChanged(app, event)
            selectedButton = app.ResolutionsButtonGroup.SelectedObject;
            app.glResolution = selectedButton.Text;
            app.glCamObj.Resolution = char(app.glResolution);
            %             disp(class(app.glResolution));
            %             disp(app.glResolution);
            
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            if isempty(app.glCamIndex)
                warndlg('Please select a camera!','Warning','modal');
                return
            end
            if isempty(app.glResolution)
                sl=app.ResolutionsButtonGroup.SelectedObject;
                app.glResolution = sl.Text;
            end
            
            %% Setup Resolution
            app.glCamObj.Resolution = char(app.glResolution);
            
            % Start preview
            preview(app.glCamObj);
            
            % Setup preview window
            %             fig = figure('NumberTitle', 'off', 'MenuBar', 'none');
            %             fig.Name = char(app.glCamName);
            %             ax = axes(fig);
            %             disp(app.glResolution);
            %             frame = snapshot(app.glCamObj);
            %             hImage = image(ax, zeros(size(frame), 'uint8'));
            %             axis(ax, 'image');
            %             %Start preview
            %             preview(app.glCamObj, hImage);
            
            %             % Set the axis of the displayed image to maintain the aspect ratio of the
            %             % incoming frame.
            %             axis image;
            % %             axis(ax, 'image');
            
            %            setappdata(hImage,'UpdatePreviewWindowFcn',@update_preview_display);
            
            function update_preview_display(obj,event,hImage)
                % This callback function updates the displayed frame and the histogram.
                % Display the current image frame.
                %             set(hImage, 'CData', event.Data);
                disp('AAAAAAAAAAAAAAAAAAAaaaa')
                % Select the second subplot on the figure for the histogram.
                %             subplot(1,2,2);
                
                % Plot the histogram. Choose 128 bins for faster update of the display.
                %             imhist(event.Data, 128);
                
                % Refresh the display.
                %             drawnow
            end
            
            %            truesize(fig,[480 540])
            
            
            %             setappdata(fig, 'CamObj', app.glCamObj);
            %             fig.CloseRequestFcn = @closePreviewWindow_Callback;
            % Local functions
            function closePreviewWindow_Callback(obj, ~)
                CamObj = getappdata(obj, 'CamObj');
                closePreview(CamObj)
                delete(obj)
            end
        end

        % Button pushed function: SelectPathButton
        function SelectPathButtonPushed(app, event)
            selected_dir = uigetdir();
            if selected_dir == 0
                warndlg('Please select an avaliable path!','Warning','modal');
                return
            end
            
            app.glSavePath = selected_dir;
            app.SavePathEditField.Value = selected_dir;
        end

        % Button pushed function: CaptureButton
        function CaptureButtonPushed(app, event)
            if isempty(app.glCamIndex)
                warndlg('Please select a camera!','Warning','modal');
                return
            end
            
            app.CaptureButton.BackgroundColor = 'red';
            savepath = app.glSavePath;  %the folder
            
            if isempty(savepath)
                filter = {'*.bmp';'*.jpg';'*.png';'*.tif';'*.gif'};
                [filename,savepath,indx] = uiputfile(filter, 'Save As', 'image_01');
                if(indx==0)
                    warndlg('Please select a save path and format!','Warning','modal');
                    return
                else
                    %                     list = {'bmp','jpg','png','tif','gif'};
                    %                     [indx,tf] = listdlg('ListString',list, ...
                    %                         'PromptString','Select a saving format', ...
                    %                         'SelectionMode', 'Single', ...
                    %                         'ListSize', [150,100]);
                    %                     if (tf == 0)
                    %                         return
                    %                     end
                    app.glSaveFormat = char(filter(indx));
                    fm = abs(app.glSaveFormat);
                    fm(fm==abs('*'))=[]; % Delect '*'
                    app.glSaveFormat = char(fm);
                    %                     disp(app.glSaveFormat)
                end
            else
                if isempty(app.glSaveFormat)
                    list = {'.bmp','.jpg','.png','.tif','.gif'};
                    [indx,tf] = listdlg('ListString',list, ...
                        'PromptString','Select a saving format', ...
                        'SelectionMode', 'Single', ...
                        'ListSize', [150,100]);
                    if (tf == 0)
                        return
                    end
                    app.glSaveFormat = char(list(indx));  
                end
                disp(app.glSaveFormat)
                filename = ['image_01' app.glSaveFormat];
            end
            
            if(app.glCaptureFrames > 1)
                %                 if isempty(app.glSaveFormat)
                %                     list = {'bmp','jpg','png','tif','gif'};
                %                     [indx,tf] = listdlg('ListString',list, ...
                %                         'PromptString','Select a saving format', ...
                %                         'SelectionMode', 'Single', ...
                %                         'ListSize', [150,100]);
                %                     if (tf == 0)
                %                         return
                %                     end
                %                     app.glSaveFormat = char(list(indx));
                %                 end
                
                nametemplate = 'image_%02d';  %name pattern
                nametemplate = [nametemplate app.glSaveFormat];
                imnum = 0;        %starting image number
                for K = 1 : app.glCaptureFrames
                    frame = snapshot(app.glCamObj); %capture the image
                    imnum = imnum + 1;
                    thisfile = sprintf(nametemplate, imnum);  %create filename
                    fullname = fullfile(savepath, thisfile);
                    %                     fullname = fullfile(savepath, filename);
                    %                     disp(thisfile)
                    %                     disp(fullname)
                    imwrite( frame, fullname);
                end
            else
                frame = snapshot(app.glCamObj); %capture one frame
                %                 filter = {'*.bmp';'*.jpg';'*.png';'*.tif';'*.gif'};
                %                 [filename,SavePath,~] = uiputfile(filter, 'Save As', 'image_01');
                %                 if (0 == savepath)
                %                     warndlg('Please select a save path!','Warning','modal');
                %                     return
                %                 end
                
                fullname = fullfile(savepath, filename);
                disp(fullname)
                imwrite( frame, fullname);
            end
            
            app.CaptureButton.BackgroundColor = 'white';
        end

        % Button pushed function: ButtonStartRecord
        function ButtonStartRecordPushed(app, event)
            if isempty(app.glCamIndex)
                warndlg('Please select a camera!','Warning','modal');
                return
            end
            
            if isempty(app.glSavePath)
                warndlg('Please select a save path!','Warning','modal');
                return
            end
            
            savepath = app.glSavePath;  % Video save path
            
            %             vidWriter = VideoWriter('frames.avi');
            %             open(vidWriter);
        end

        % Value changed function: CaptureFramesEditField
        function CaptureFramesEditFieldValueChanged(app, event)
            app.glCaptureFrames = app.CaptureFramesEditField.Value;
            
        end

        % Value changed function: SavePathEditField
        function SavePathEditFieldValueChanged(app, event)
            app.glSavePath = app.SavePathEditField.Value;
        end

        % Value changed function: CheckBox_slash_LT_RD
        function CheckBox_slash_LT_RDValueChanged(app, event)
            value = app.CheckBox_slash_LT_RD.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AiCamUIFigure = uifigure;
            app.AiCamUIFigure.Name = 'AiCam';
            app.FileMenu = uimenu(app.AiCamUIFigure);
            app.FileMenu.Text = 'File';
            app.DevicesMenu = uimenu(app.AiCamUIFigure);
            app.DevicesMenu.Text = 'Devices';
            app.OptionsMenu = uimenu(app.AiCamUIFigure);
            app.OptionsMenu.Text = 'Options';
            app.CaptureMenu = uimenu(app.AiCamUIFigure);
            app.CaptureMenu.Text = 'Capture';
            app.TabGroup = uitabgroup(app.AiCamUIFigure);
            app.PreviewTab = uitab(app.TabGroup);
            app.PreviewTab.Title = 'Preview';
            app.CameraListListBoxLabel = uilabel(app.PreviewTab);
            app.CameraListListBoxLabel.Text = 'Camera List';
            app.CameraListListBox = uilistbox(app.PreviewTab);
            app.CameraListListBox.Items = {};
            app.CameraListListBox.ValueChangedFcn = createCallbackFcn(app, @CameraListListBoxValueChanged, true);
            app.CameraListListBox.Value = {};
            app.ResolutionsButtonGroup = uibuttongroup(app.PreviewTab);
            app.ResolutionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResolutionsButtonGroupSelectionChanged, true);
            app.ResolutionsButtonGroup.Title = 'Resolutions';
            app.PreviewButton = uibutton(app.PreviewTab, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.CheckBox_slash_LT_RD = uicheckbox(app.PreviewTab);
            app.CheckBox_slash_LT_RD.ValueChangedFcn = createCallbackFcn(app, @CheckBox_slash_LT_RDValueChanged, true);
            app.CheckBox_slash_LT_RD.Text = 'LT_RD';
            app.CaptureRecordTab = uitab(app.TabGroup);
            app.CaptureRecordTab.Title = 'Capture&Record';
            app.SavePathEditFieldLabel = uilabel(app.CaptureRecordTab);
            app.SavePathEditFieldLabel.Text = 'Save Path:';
            app.SavePathEditField = uieditfield(app.CaptureRecordTab, 'text');
            app.SavePathEditField.ValueChangedFcn = createCallbackFcn(app, @SavePathEditFieldValueChanged, true);
            app.SelectPathButton = uibutton(app.CaptureRecordTab, 'push');
            app.SelectPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectPathButtonPushed, true);
            app.SelectPathButton.Text = '...';
            app.CapturePanel = uipanel(app.CaptureRecordTab);
            app.CapturePanel.Title = 'Capture';
            app.CaptureFramesLabel = uilabel(app.CapturePanel);
            app.CaptureFramesLabel.Text = 'Capture Frames:';
            app.CaptureFramesEditField = uieditfield(app.CapturePanel, 'numeric');
            app.CaptureFramesEditField.ValueChangedFcn = createCallbackFcn(app, @CaptureFramesEditFieldValueChanged, true);
            app.CaptureFramesEditField.Value = 1;
            app.CaptureButton = uibutton(app.CapturePanel, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @CaptureButtonPushed, true);
            app.CaptureButton.Text = 'Capture';
            app.RecordPanel = uipanel(app.CaptureRecordTab);
            app.RecordPanel.Title = 'Record';
            app.ButtonStartRecord = uibutton(app.RecordPanel, 'push');
            app.ButtonStartRecord.ButtonPushedFcn = createCallbackFcn(app, @ButtonStartRecordPushed, true);
            app.ButtonStartRecord.Icon = 'RecordStart.jpg';
            app.ButtonStartRecord.Text = '';
            app.ButtonStopRecord = uibutton(app.RecordPanel, 'push');
            app.ButtonStopRecord.Icon = 'RecordStop.jpg';
            app.ButtonStopRecord.Text = '';
            app.TotalLengthEditField = uieditfield(app.RecordPanel, 'text');
            app.TotalLengthEditField.Value = '00:00:00 00';
            app.RecordStateLamp = uilamp(app.RecordPanel);
            app.UIAxes = uiaxes(app.CaptureRecordTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.ImageProcessTab = uitab(app.TabGroup);
            app.ImageProcessTab.Title = 'Image Process';
        end
    end

    methods (Access = public)

        % Construct app
        function app = AiCam

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AiCamUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AiCamUIFigure)
        end
    end
end